classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        B2A0                matlab.ui.control.EditField
        B2A1                matlab.ui.control.EditField
        B2A2                matlab.ui.control.EditField
        B2A3                matlab.ui.control.EditField
        B2AEditFieldLabel   matlab.ui.control.Label
        A2B0                matlab.ui.control.EditField
        Label_3             matlab.ui.control.Label
        A2B1                matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        A2B2                matlab.ui.control.EditField
        EditField_3Label_2  matlab.ui.control.Label
        A2B3                matlab.ui.control.EditField
        EditFielLabel       matlab.ui.control.Label
        CalculateButton     matlab.ui.control.Button
        y0                  matlab.ui.control.NumericEditField
        EditField_3Label    matlab.ui.control.Label
        y1                  matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        y2                  matlab.ui.control.NumericEditField
        EditFieldLabel_2    matlab.ui.control.Label
        y3                  matlab.ui.control.NumericEditField
        yEditFieldLabel     matlab.ui.control.Label
        x0                  matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        x1                  matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        x2                  matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        x3                  matlab.ui.control.NumericEditField
        xEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        x=zeros(1,4);
        y=zeros(1,4);
        A2B=zeros(1,4);
        B2A=zeros(1,4);
        device = serialport("/dev/ttyUSB1",115200)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: x3
        function x3ValueChanged(app, event)
            app.x(4)  = app.x3.Value;
        end

        % Value changed function: x2
        function x2ValueChanged(app, event)
            app.x(3)  = app.x2.Value;
        end

        % Value changed function: x1
        function x1ValueChanged(app, event)
            app.x(2)  = app.x1.Value;
        end

        % Value changed function: x0
        function x0ValueChanged(app, event)
            app.x(1)  = app.x0.Value;
        end

        % Value changed function: y3
        function y3ValueChanged(app, event)
            app.y(4)  = app.y3.Value;
        end

        % Value changed function: y2
        function y2ValueChanged(app, event)
            app.y(3)  = app.y2.Value;
        end

        % Value changed function: y1
        function y1ValueChanged(app, event)
            app.y(2)  = app.y1.Value;
        end

        % Value changed function: y0
        function y0ValueChanged(app, event)
            app.y(1)  = app.y0.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            write(app.device, app.x(1:4), "uint8");
            write(app.device, app.y(1:4), "uint8");
            flush(app.device);
            a2b_temp = hex2dec(readline(app.device));
            b2a_temp = hex2dec(readline(app.device));
            
            for i=1:4 
                app.A2B(i) = rem(uint32(a2b_temp), 2 ^ 8);
                a2b_temp = floor((a2b_temp) / (2 ^ 8));
            end
            
            app.A2B0.Value=dec2hex(app.A2B(1), 2);
            app.A2B1.Value=dec2hex(app.A2B(2), 2);
            app.A2B2.Value=dec2hex(app.A2B(3), 2);
            app.A2B3.Value=dec2hex(app.A2B(4), 2);

            for i=1:4 
                app.B2A(i) = rem(uint32(b2a_temp), 2 ^ 8);
                b2a_temp = floor((b2a_temp) / (2 ^ 8));
            end
            
            app.B2A0.Value=dec2hex(app.B2A(1), 2);
            app.B2A1.Value=dec2hex(app.B2A(2), 2);
            app.B2A2.Value=dec2hex(app.B2A(3), 2);
            app.B2A3.Value=dec2hex(app.B2A(4), 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.x3 = uieditfield(app.UIFigure, 'numeric');
            app.x3.Limits = [0 255];
            app.x3.ValueChangedFcn = createCallbackFcn(app, @x3ValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.x2 = uieditfield(app.UIFigure, 'numeric');
            app.x2.Limits = [0 255];
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2ValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.x1 = uieditfield(app.UIFigure, 'numeric');
            app.x1.Limits = [0 255];
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.x0 = uieditfield(app.UIFigure, 'numeric');
            app.x0.Limits = [0 255];
            app.x0.ValueChangedFcn = createCallbackFcn(app, @x0ValueChanged, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.y3 = uieditfield(app.UIFigure, 'numeric');
            app.y3.Limits = [0 255];
            app.y3.ValueChangedFcn = createCallbackFcn(app, @y3ValueChanged, true);
            app.EditFieldLabel_2 = uilabel(app.UIFigure);
            app.EditFieldLabel_2.Text = '';
            app.y2 = uieditfield(app.UIFigure, 'numeric');
            app.y2.Limits = [0 255];
            app.y2.ValueChangedFcn = createCallbackFcn(app, @y2ValueChanged, true);
            app.EditField_2Label_2 = uilabel(app.UIFigure);
            app.EditField_2Label_2.Text = '';
            app.y1 = uieditfield(app.UIFigure, 'numeric');
            app.y1.Limits = [0 255];
            app.y1.ValueChangedFcn = createCallbackFcn(app, @y1ValueChanged, true);
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.y0 = uieditfield(app.UIFigure, 'numeric');
            app.y0.Limits = [0 255];
            app.y0.ValueChangedFcn = createCallbackFcn(app, @y0ValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EditFielLabel = uilabel(app.UIFigure);
            app.EditFielLabel.Text = 'A2B';
            app.A2B3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = '';
            app.A2B2 = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.A2B1 = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.A2B0 = uieditfield(app.UIFigure, 'text');
            app.B2AEditFieldLabel = uilabel(app.UIFigure);
            app.B2AEditFieldLabel.Text = 'B2A';
            app.B2A3 = uieditfield(app.UIFigure, 'text');
            app.B2A2 = uieditfield(app.UIFigure, 'text');
            app.B2A1 = uieditfield(app.UIFigure, 'text');
            app.B2A0 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end